classdef TunnelApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        DynamicPressureGauge            matlab.ui.control.LinearGauge
        DynamicPressureGaugeLabel       matlab.ui.control.Label
        AoAPortPressureDifferenceGauge  matlab.ui.control.LinearGauge
        AoAPortPressureDifferenceGaugeLabel  matlab.ui.control.Label
        AngleofAttackGauge              matlab.ui.control.SemicircularGauge
        AngleofAttackGaugeLabel         matlab.ui.control.Label
        MeasuredAirspeedGauge           matlab.ui.control.Gauge
        MeasuredAirspeedGaugeLabel      matlab.ui.control.Label
        STOPButton                      matlab.ui.control.Button
        SETSPEEDButton                  matlab.ui.control.Button
        SpeedSelectionKnob              matlab.ui.control.Knob
        SpeedSelectionKnobLabel         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PlotsTab                        matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ClearPlotButton_2               matlab.ui.control.Button
        ClearPlotButton                 matlab.ui.control.Button
        FreezeButton_2                  matlab.ui.control.Button
        FreezeButton                    matlab.ui.control.Button
        AoaPlot                         matlab.ui.control.UIAxes
        AspdPlot                        matlab.ui.control.UIAxes
        InfoTab                         matlab.ui.container.Tab
    end

    
    properties (Access = public)
        ams_sensors % Dict of AMS pressure sensors with key=address
        port_name = "/dev/ttyACM1"  % Name of the serail port connected to the MC
        port = serialport("/dev/ttyACM1", 9600);
        uut_speeds = []
        aoa_pres = []
        runTimer
        ref_aspd
        density = 1.225;
    end
    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% FUNCTIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    methods (Access = private)
        function [] = readData(app)
            num_lines = 4;  % Number of serial lines to read
            flush(app.port); % Clear unread messages
            data = [];
            while app.port.NumBytesAvailable < 4   % Wait until the serial port is ready
            end
            
            for i = 1:num_lines
                if i == 1
                    useless = readline(app.port);   % Dump the first line to an unused variable
                end
                string = readline(app.port);
                line = str2double(strsplit(string, "\t"));
                data = [data; line];
            end
            
            aoa_dat = data(data(:, 1)==1, :);
            ref_dat = data(data(:, 1)==3, :);
            uut_dat = data(data(:, 1)==4, :);
            app.uut_speeds = circshift(app.uut_speeds, -1);
            app.aoa_pres = circshift(app.aoa_pres, -1);
            app.uut_speeds(end) = uut_dat(3);
            app.aoa_pres(end) = aoa_dat(2);
            plot(app.AspdPlot, app.uut_speeds);
            plot(app.AoaPlot, app.aoa_pres);
            
            app.DynamicPressureGauge.Value = ref_dat(2);
            app.MeasuredAirspeedGauge.Value = ref_dat(3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.uut_speeds = zeros(1000);
            app.aoa_pres = zeros(1000);


            app.runTimer = timer("ExecutionMode", "fixedspacing", ...
               "Period", 0.1, "TasksToExecute", Inf, ...
               "BusyMode", "queue");
            app.runTimer.TimerFcn = @(~, ~)app.readData();
            start(app.runTimer);
            
            
        end

        % Button pushed function: SETSPEEDButton
        function SETSPEEDButtonPushed(app, event)
            spdSetting = app.SpeedSelectionKnob.Value;
            writeline(app.port, num2str(spdSetting));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            writeline(app.port, "0");
            delete(app.port);
            app.SpeedSelectionKnob.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.GridLayout2 = uigridlayout(app.PlotsTab);
            app.AspdPlot = uiaxes(app.GridLayout2);
            title(app.AspdPlot, 'UUT Airspeed')
            xlabel(app.AspdPlot, 'X')
            ylabel(app.AspdPlot, 'Y')
            zlabel(app.AspdPlot, 'Z')
            app.AoaPlot = uiaxes(app.GridLayout2);
            title(app.AoaPlot, 'UUT Angle of Attack')
            xlabel(app.AoaPlot, 'X')
            ylabel(app.AoaPlot, 'Y')
            zlabel(app.AoaPlot, 'Z')
            app.FreezeButton = uibutton(app.GridLayout2, 'push');
            app.FreezeButton.Text = 'Freeze';
            app.FreezeButton_2 = uibutton(app.GridLayout2, 'push');
            app.FreezeButton_2.Text = 'Freeze';
            app.ClearPlotButton = uibutton(app.GridLayout2, 'push');
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ClearPlotButton_2 = uibutton(app.GridLayout2, 'push');
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.SpeedSelectionKnobLabel = uilabel(app.GridLayout);
            app.SpeedSelectionKnobLabel.Text = 'Speed Selection';
            app.SpeedSelectionKnob = uiknob(app.GridLayout, 'continuous');
            app.SpeedSelectionKnob.Limits = [0 80];
            app.SpeedSelectionKnob.MajorTicks = [0 10 20 30 40 50 60 70 80];
            app.SETSPEEDButton = uibutton(app.GridLayout, 'push');
            app.SETSPEEDButton.ButtonPushedFcn = createCallbackFcn(app, @SETSPEEDButtonPushed, true);
            app.SETSPEEDButton.Text = 'SET SPEED';
            app.STOPButton = uibutton(app.GridLayout, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.MeasuredAirspeedGaugeLabel = uilabel(app.GridLayout);
            app.MeasuredAirspeedGaugeLabel.Text = 'Measured Airspeed';
            app.MeasuredAirspeedGauge = uigauge(app.GridLayout, 'circular');
            app.MeasuredAirspeedGauge.Limits = [0 80];
            app.MeasuredAirspeedGauge.MajorTicks = [0 10 20 30 40 50 60 70 80];
            app.AngleofAttackGaugeLabel = uilabel(app.GridLayout);
            app.AngleofAttackGaugeLabel.Text = 'Angle of Attack';
            app.AngleofAttackGauge = uigauge(app.GridLayout, 'semicircular');
            app.AngleofAttackGauge.Limits = [-50 50];
            app.AngleofAttackGauge.MajorTicks = [-45 -35 -25 -15 -5 5 15 25 35 45];
            app.AngleofAttackGauge.MinorTicks = [-45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45];
            app.AoAPortPressureDifferenceGaugeLabel = uilabel(app.GridLayout);
            app.AoAPortPressureDifferenceGaugeLabel.Text = 'AoA Port Pressure Difference';
            app.AoAPortPressureDifferenceGauge = uigauge(app.GridLayout, 'linear');
            app.AoAPortPressureDifferenceGauge.Limits = [-100 100];
            app.DynamicPressureGaugeLabel = uilabel(app.GridLayout);
            app.DynamicPressureGaugeLabel.Text = 'Dynamic Pressure';
            app.DynamicPressureGauge = uigauge(app.GridLayout, 'linear');
            app.DynamicPressureGauge.Limits = [0 3000];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TunnelApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end